classdef Interface_redes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        General           matlab.ui.container.TabGroup
        Inicio            matlab.ui.container.Tab
        Tipo_1            matlab.ui.control.EditField
        UniversidaddelasFuerzasArmadasEspeLabel  matlab.ui.control.Label
        Tipo_2            matlab.ui.control.EditField
        Tipo_3            matlab.ui.control.EditField
        Tipo_4            matlab.ui.control.EditField
        Tipo_5            matlab.ui.control.EditField
        Tipo_6            matlab.ui.control.EditField
        PostesLabel       matlab.ui.control.Label
        LongitudLabel     matlab.ui.control.Label
        TipoLabel         matlab.ui.control.Label
        Calibre_1         matlab.ui.control.EditField
        CalibreLabel      matlab.ui.control.Label
        Calibre_2         matlab.ui.control.EditField
        Calibre_3         matlab.ui.control.EditField
        Calibre_4         matlab.ui.control.EditField
        Calibre_5         matlab.ui.control.EditField
        Calibre_6         matlab.ui.control.EditField
        Longitud_1        matlab.ui.control.EditField
        Longitud_2        matlab.ui.control.EditField
        Longitud_3        matlab.ui.control.EditField
        Longitud_4        matlab.ui.control.EditField
        Longitud_5        matlab.ui.control.EditField
        Longitud_6        matlab.ui.control.EditField
        Postes_1          matlab.ui.control.EditField
        Postes_2          matlab.ui.control.EditField
        Postes_3          matlab.ui.control.EditField
        Postes_4          matlab.ui.control.EditField
        Postes_5          matlab.ui.control.EditField
        Postes_6          matlab.ui.control.EditField
        ActualizarButton  matlab.ui.control.Button
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc,clear all,close all;
            global POSTES LONG TIPO CALIBRE;
            POSTES=[];
            LONG=[];
        end

        % Value changed function: Postes_1
        function Postes_1ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_1.Value))
                POSTES(1)= [];
            else
                POSTES(1)= eval(app.Postes_1.Value);
            end
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            global POSTES LONG TIPO CALIBRE;
            
            %% generacion de la variable auxiliar para verificar lac columnas
            auxi_dimension=[length(POSTES),length(LONG),length(TIPO),length(CALIBRE)];
            
            bandera_columna=all(auxi_dimension==length(POSTES))
            
            if(all(auxi_dimension==length(POSTES)))
                disp("Se han cargado correctamente los datos");
                Total=[POSTES;LONG;TIPO;CALIBRE]
            else
                disp("Colorar correctamente los datos");
            end
        end

        % Value changed function: Postes_2
        function Postes_2ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_2.Value))
                POSTES(2)= [];
            else
                POSTES(2)= eval(app.Postes_2.Value);
            end
        end

        % Value changed function: Postes_3
        function Postes_3ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_3.Value))
                POSTES(3)= [];
            else
                POSTES(3)= eval(app.Postes_3.Value);
            end
        end

        % Value changed function: Longitud_1
        function Longitud_1ValueChanged(app, event)
            global LONG;
            if (isempty(app.Longitud_1.Value))
               LONG(1)=[];
            else
                LONG(1)=eval(app.Longitud_1.Value);
            end
        end

        % Value changed function: Longitud_2
        function Longitud_2ValueChanged(app, event)
            global LONG;
            if (isempty(app.Longitud_2.Value))
               LONG(2)=[];
            else
                LONG(2)=eval(app.Longitud_2.Value);
            end
        end

        % Value changed function: Longitud_3
        function Longitud_3ValueChanged(app, event)
            global LONG;
            if (esempty(app.Longitud_3.Value))
               LONG(3)=[];
            else
                LONG(3)=eval(app.Longitud_3.Value);
            end
        end

        % Value changed function: Tipo_1
        function Tipo_1ValueChanged(app, event)
            global TIPO;
            aux_dimension=length(TIPO);
            if(aux_dimension>1)
                if (isempty(app.Tipo_1.Value))
                    TIPO(1)=[];
                else
                    TIPO(1)= convertCharsToStrings(app.Tipo_1.Value);
                end
            else
                if (isempty(app.Tipo_1.Value))
                    TIPO=[];
                else
                    TIPO = convertCharsToStrings(app.Tipo_1.Value);
                end
            end


        end

        % Value changed function: Tipo_2
        function Tipo_2ValueChanged(app, event)
            global TIPO;
            if (isempty(app.Tipo_2.Value))
                TIPO(2) = [];
            else
                TIPO(2) = convertCharsToStrings(app.Tipo_2.Value);
            end
            
            
        end

        % Value changed function: Tipo_3
        function Tipo_3ValueChanged(app, event)
            global TIPO;
            if (isempty(app.Tipo_3.Value))
                TIPO(3) = [];
            else
                TIPO(3) = convertCharsToStrings(app.Tipo_3.Value);
            end


        end

        % Value changed function: Calibre_1
        function Calibre_1ValueChanged(app, event)
            global CALIBRE;
            aux_dimension=length(CALIBRE);
            if(aux_dimension>1)
                if (isempty(app.Calibre_1.Value))
                    CALIBRE(1)=[];
                else
                    CALIBRE(1)= convertCharsToStrings(app.Calibre_1.Value);
                end
            else
                if (isempty(app.Calibre_1.Value))
                    CALIBRE=[];
                else
                    CALIBRE = convertCharsToStrings(app.Calibre_1.Value);
                end
            end
        end

        % Value changed function: Calibre_2
        function Calibre_2ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_2.Value))
                CALIBRE(2) = [];
            else
                CALIBRE(2) = convertCharsToStrings(app.Calibre_2.Value);
            end
        end

        % Value changed function: Calibre_3
        function Calibre_3ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_3.Value))
                CALIBRE(3) = [];
            else
                CALIBRE(3) = convertCharsToStrings(app.Calibre_3.Value);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.General = uitabgroup(app.UIFigure);
            app.Inicio = uitab(app.General);
            app.Inicio.Title = 'Calculo de caida de voltaje';
            app.Tipo_1 = uieditfield(app.Inicio, 'text');
            app.Tipo_1.ValueChangedFcn = createCallbackFcn(app, @Tipo_1ValueChanged, true);
            app.UniversidaddelasFuerzasArmadasEspeLabel = uilabel(app.Inicio);
            app.UniversidaddelasFuerzasArmadasEspeLabel.Text = 'Universidad de las Fuerzas Armadas Espe';
            app.Tipo_2 = uieditfield(app.Inicio, 'text');
            app.Tipo_2.ValueChangedFcn = createCallbackFcn(app, @Tipo_2ValueChanged, true);
            app.Tipo_3 = uieditfield(app.Inicio, 'text');
            app.Tipo_3.ValueChangedFcn = createCallbackFcn(app, @Tipo_3ValueChanged, true);
            app.Tipo_4 = uieditfield(app.Inicio, 'text');
            app.Tipo_5 = uieditfield(app.Inicio, 'text');
            app.Tipo_6 = uieditfield(app.Inicio, 'text');
            app.PostesLabel = uilabel(app.Inicio);
            app.PostesLabel.Text = 'Postes';
            app.LongitudLabel = uilabel(app.Inicio);
            app.LongitudLabel.Text = 'Longitud';
            app.TipoLabel = uilabel(app.Inicio);
            app.TipoLabel.Text = 'Tipo';
            app.Calibre_1 = uieditfield(app.Inicio, 'text');
            app.Calibre_1.ValueChangedFcn = createCallbackFcn(app, @Calibre_1ValueChanged, true);
            app.CalibreLabel = uilabel(app.Inicio);
            app.CalibreLabel.Text = 'Calibre';
            app.Calibre_2 = uieditfield(app.Inicio, 'text');
            app.Calibre_2.ValueChangedFcn = createCallbackFcn(app, @Calibre_2ValueChanged, true);
            app.Calibre_3 = uieditfield(app.Inicio, 'text');
            app.Calibre_3.ValueChangedFcn = createCallbackFcn(app, @Calibre_3ValueChanged, true);
            app.Calibre_4 = uieditfield(app.Inicio, 'text');
            app.Calibre_5 = uieditfield(app.Inicio, 'text');
            app.Calibre_6 = uieditfield(app.Inicio, 'text');
            app.Longitud_1 = uieditfield(app.Inicio, 'text');
            app.Longitud_1.ValueChangedFcn = createCallbackFcn(app, @Longitud_1ValueChanged, true);
            app.Longitud_2 = uieditfield(app.Inicio, 'text');
            app.Longitud_2.ValueChangedFcn = createCallbackFcn(app, @Longitud_2ValueChanged, true);
            app.Longitud_3 = uieditfield(app.Inicio, 'text');
            app.Longitud_3.ValueChangedFcn = createCallbackFcn(app, @Longitud_3ValueChanged, true);
            app.Longitud_4 = uieditfield(app.Inicio, 'text');
            app.Longitud_5 = uieditfield(app.Inicio, 'text');
            app.Longitud_6 = uieditfield(app.Inicio, 'text');
            app.Postes_1 = uieditfield(app.Inicio, 'text');
            app.Postes_1.ValueChangedFcn = createCallbackFcn(app, @Postes_1ValueChanged, true);
            app.Postes_2 = uieditfield(app.Inicio, 'text');
            app.Postes_2.ValueChangedFcn = createCallbackFcn(app, @Postes_2ValueChanged, true);
            app.Postes_3 = uieditfield(app.Inicio, 'text');
            app.Postes_3.ValueChangedFcn = createCallbackFcn(app, @Postes_3ValueChanged, true);
            app.Postes_4 = uieditfield(app.Inicio, 'text');
            app.Postes_5 = uieditfield(app.Inicio, 'text');
            app.Postes_6 = uieditfield(app.Inicio, 'text');
            app.ActualizarButton = uibutton(app.Inicio, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface_redes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end