classdef Interface_redes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        General                        matlab.ui.container.TabGroup
        Inicio                         matlab.ui.container.Tab
        Tipo_1                         matlab.ui.control.EditField
        UniversidadUTELabel            matlab.ui.control.Label
        Tipo_2                         matlab.ui.control.EditField
        Tipo_3                         matlab.ui.control.EditField
        Tipo_4                         matlab.ui.control.EditField
        Tipo_5                         matlab.ui.control.EditField
        Tipo_6                         matlab.ui.control.EditField
        PostesLabel                    matlab.ui.control.Label
        LongitudLabel                  matlab.ui.control.Label
        TipoLabel                      matlab.ui.control.Label
        Calibre_1                      matlab.ui.control.EditField
        CalibreLabel                   matlab.ui.control.Label
        Calibre_2                      matlab.ui.control.EditField
        Calibre_3                      matlab.ui.control.EditField
        Calibre_4                      matlab.ui.control.EditField
        Calibre_5                      matlab.ui.control.EditField
        Calibre_6                      matlab.ui.control.EditField
        Longitud_1                     matlab.ui.control.EditField
        Longitud_2                     matlab.ui.control.EditField
        Longitud_3                     matlab.ui.control.EditField
        Longitud_4                     matlab.ui.control.EditField
        Longitud_5                     matlab.ui.control.EditField
        Longitud_6                     matlab.ui.control.EditField
        Postes_1                       matlab.ui.control.EditField
        Postes_2                       matlab.ui.control.EditField
        Postes_3                       matlab.ui.control.EditField
        Postes_4                       matlab.ui.control.EditField
        Postes_5                       matlab.ui.control.EditField
        Postes_6                       matlab.ui.control.EditField
        ActualizarButton               matlab.ui.control.Button
        Cargar                         matlab.ui.control.Button
        DatosLabel                     matlab.ui.control.Label
        CalculosTab                    matlab.ui.container.Tab
        UniversidadUTELabel_2          matlab.ui.control.Label
        CaidavoltajesLabel             matlab.ui.control.Label
        CalculoVoltajesButton          matlab.ui.control.Button
        Errores_posicion_proporcional  matlab.ui.control.UIAxes
        VoltajeEditFieldLabel          matlab.ui.control.Label
        VoltajeEditField               matlab.ui.control.NumericEditField
        ResistividadEditFieldLabel     matlab.ui.control.Label
        ResistividadEditField          matlab.ui.control.NumericEditField
        DiametroEditFieldLabel         matlab.ui.control.Label
        DiametroEditField              matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        
        function [BasedatosS1] = Consumo_poste(app,N)
            [~, ~, raw] = xlsread('Base_datos.xlsm','CONSUMO POR POSTE');
            raw = raw(7:N,1:5);
            raw(cellfun(@(x) ~isempty(x) && isnumeric(x) && isnan(x),raw)) = {''};
            
            %% Replace non-numeric cells with NaN
            R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),raw); % Find non-numeric cells
            raw(R) = {NaN}; % Replace non-numeric cells
            
            %% Create output variable
            data = reshape([raw{:}],size(raw));
            
            %% Create table
            BasedatosS1 = table;
            
            %% Allocate imported array to column variable names
            BasedatosS1.Item = data(:,1);
            BasedatosS1.Poste = data(:,2);
            BasedatosS1.Consumo = data(:,3);
            BasedatosS1.Cantidad = data(:,4);
            BasedatosS1.Numero = data(:,5);
            
            %% Clear temporary variables
            clearvars data raw;
        end
        
        function [Basedatos] = kvam(app)
            %% Import the data
            [~, ~, raw] = xlsread('Base_datos.xlsm','kVA-m');
            raw = raw(2:36,1:8);
            raw(cellfun(@(x) ~isempty(x) && isnumeric(x) && isnan(x),raw)) = {''};
            stringVectors = string(raw(:,[1,2]));
            stringVectors(ismissing(stringVectors)) = '';
            raw = raw(:,[3,4,5,6,7,8]);
            
            %% Replace non-numeric cells with NaN
            R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),raw); % Find non-numeric cells
            raw(R) = {NaN}; % Replace non-numeric cells
            
            %% Create output variable
            data = reshape([raw{:}],size(raw));
            
            %% Create table
            Basedatos = table;
            
            %% Allocate imported array to column variable names
            Basedatos.CONDUCTOR = stringVectors(:,1);
            Basedatos.TIPOFASE = categorical(stringVectors(:,2));
            Basedatos.SECCION = data(:,1);
            Basedatos.FASE = data(:,2);
            Basedatos.NEUTRO = data(:,3);
            Basedatos.Trifsica = data(:,4);
            Basedatos.Bifsica = data(:,5);
            Basedatos.Monofsica = data(:,6);
            
            %% Clear temporary variables
            clearvars data raw stringVectors R;
        end
        
        function [consumo,aux] = datos_consumo(app,postes,consumo_poste)
            consumo_total=consumo_poste.Poste==str2double(postes);
            
            %% variables auxiliares para poder almacenar los datos respectivos
            [i_c,j_c]=size(consumo_total);
            
            
            %% bucle de interaccion para verificar si los datos son los correctos  de los postes
            
            for k=1:j_c
                
                auxiliar_consumo=consumo_poste.Consumo(consumo_total(:,k));
                if(isempty(auxiliar_consumo))
                    %disp("un valor intorducido no se encuentra en la bas de datos no se puede continuar");
                    aux=0;
                    consumo(k)=0;
                    mensaje="Un poste en la columna "+num2str(k)+"  no se encuentra en la base de datos";
                    disp(mensaje);
                    msgbox(mensaje,'Aviso Interface');
                    break
                else
                    consumo(k)=auxiliar_consumo;
                    aux=1;
                end
            end
        end
        
        function [kv,aux] = tipo(app,tipo_conexion,kva_m,conductor)
                %UNTITLED10 Summary of this function goes here
                %   Detailed explanation goes here
                if (strcmp(tipo_conexion,"monofasica"))
                    %disp("monofasic");
                    dato=kva_m.Monofsica;
                    aux=1;
                elseif (strcmp(tipo_conexion,"bifasica"))
                    %disp("bifasic");
                    dato=kva_m.Bifsica;
                    aux=1;
                elseif (strcmp(tipo_conexion,"trifasica"))
                %     disp("trifasic");
                    dato=kva_m.Trifsica;
                    aux=1;
                else
                    %disp("Se coloco mal el tipo de conexion");
                    dato=0;
                    aux=0;
                end
                
                if aux==1
                    kv=0;
                    for k=1:length(conductor)
                        auxiliar_tipo=dato(strcmp(kva_m.CONDUCTOR,conductor(1,k)));
                        if(isempty(auxiliar_tipo))
                            %disp("un valor intorducido no se encuentra en la bas de datos no se puede continuar");
                            aux=0;
                            kv(k)=0;
                            mensaje="Un Tipo o calibre en la columna "+num2str(k)+"  no se encuentra en la base de datos";
                            disp(mensaje);
                            msgbox(mensaje,'Aviso Interface');
                            break
                        else
                            kv(k)=auxiliar_tipo;
                            aux=1;
                        end
                    end
                    
                else
                kv=0;
                end
        end
        
        function [Datos,aux_final] = generacion_datos(app,postes,long,consumo,aux_c,conexion,aux_t)
            if(aux_c==1)&&(aux_t==1)
                disp("Se han cargado correctamente los datos");
                mensaje_inicio='Se han cargado correctamente los datos';
                msgbox(mensaje_inicio,'Aviso Interface');
                Datos=[postes;...
                    long;...
                    consumo;...
                    conexion];
                aux_final=1;
               
            elseif(aux_c==1)&&(aux_t==0)
%                 disp("Existe un error en los datos de kva_m")
                Datos=0;
                aux_final=0;
            elseif(aux_c==0)&&(aux_t==1)
%                 disp("Existe un error en los datos de los Postes")
                Datos=0;
                aux_final=0;
            else
%                 disp("Se han colocado mal los datos de Kva_m y postes")
                Datos=0;
                aux_final=0;
            end
        end
        
        function nuevo = aux_posiciones(app,posiciones)
            [i_total,j_total]=size(posiciones);

                for i=1:j_total
                   if i==1
                   nuevo(:,i)=posiciones(:,i);
                   else
                       sumatoria=sum(posiciones(2,1:i));
                       nuevo(1,i)=nuevo(2,i-1)+1;
                       nuevo(2,i)=sumatoria;
                   end
                   
                    
                end
                
        end
        
        function [Total_final,posiciones,C] = voltajes_extremos(app,nuevo,Total)
            [x1,y1]=size(nuevo);
            Total_final=Total;
            posiciones=[];
            for k=1:nuevo(2,end)
                valor(k)=Total(1,k);
                [r,c]=find(Total==valor(k));
                if length(c)>1
                    aux=Total(1,c-1);
                    if all(aux==aux(1))
                        %           disp('no es nodo')
                        C=0;
                    else
                        C=c;
                        c(find(c~=k))=c(find(c~=k))-1;
                        %           disp('es nodo')
                        valor_1=0;
                        suma_total=[];
                        
                        for j=1:y1
                            creacion=[nuevo(1,j):1:nuevo(2,j)];
                            sumatoria=[];
                            suma=0;
                            for jj=1:length(c)
                                aux2=find(creacion==c(jj));
                                
                                if isempty(aux2)
                                    %                     disp('No se suma');
                                    sumatoria(jj)=0;
                                    
                                else
                                    a=[];
                                    c(jj);
                                    for jjj=1:length(creacion)
                                        
                                        if(creacion(jjj)==c(jj))
                                            if find(C==c(jj))
                                                aux=Total(3,creacion(jjj));
                                                a=[a,aux];
                                                break;
                                            else
                                                
                                                [r1,c1]=find(Total==Total(1,creacion(jjj)));
                                                aux=Total(3,creacion(jjj))/(length(c1));
                                                a=[a,aux];
                                                
                                                break;
                                            end
                                            
                                        else
                                            [r1,c1]=find(Total==Total(1,creacion(jjj)));
                                            
                                            aux=Total(3,creacion(jjj))/(length(c1));
                                            a=[a,aux];
                                        end
                                        
                                    end
                                    sumatoria(jj)=sum(a);
                                    %                     disp('se suma');
                                    
                                end
                                
                            end
                            suma=sum(sumatoria);
                            suma_total(j)=suma;
                        end
                        
                        valor_1=sum(suma_total);
                        
                        Total_final(3,C')=valor_1;
                        
                    end
                    
                else
                    C=0;
                end
                posiciones=[posiciones;C];
            end
        end
        
        function [resultado,Datos] = extremos_final(app,Total,nuevo,C,Parametros)
            %UNTITLED2 Summary of this function goes here
            %   Detailed explanation goes here
            %% generacion de las constantes para el calculo de la perdida
            v=Parametros.voltage;
            radio_cuadrado=Parametros.operador;
            resistividad=Parametros.resistividad;
            
            [x1,y1]=size(nuevo);
            for j=1:y1
                creacion=[nuevo(1,j):1:nuevo(2,j)];
                [i_dato,j_dato]=size(creacion);
                total=[];
                total_2=[];
                
                for k=1:j_dato
                    for kk=1:length(C)
                        if creacion(1,k)==C(kk)
                            acumulado(creacion(1,k))=Total(3,creacion(1,k));
                            
                            %% seccion para las perdidas
                            [r1,c1]=find(Total==Total(1,creacion(1,k)));
                            
                            acumulado_aux(creacion(1,k))=Total(3,creacion(1,k));
                            
                            Lt_aux(creacion(1,k))=Total(2,creacion(1,k))*acumulado_aux((creacion(1,k)));
                            
                            Parcial_aux(creacion(1,k))=Lt_aux(creacion(1,k))/Total(4,creacion(1,k));
                            
                            subtotal(creacion(1,k))=120*Parcial_aux(creacion(1,k))/100;
                            
                            corriente_conductor(creacion(1,k))=((acumulado_aux(creacion(1,k))*0.86*1000)/(v-subtotal(creacion(1,k))))/length(c1);
                            
                            resistencia_conductor(creacion(1,k))=resistividad*(Total(2,creacion(1,k))/radio_cuadrado);
                            
                            perdidaa_potencia(creacion(1,k))=resistencia_conductor(creacion(1,k))*( corriente_conductor(creacion(1,k))^2)*length(c1);
                            
                            total_2(k)=resistencia_conductor(creacion(1,k))*( corriente_conductor(creacion(1,k))^2)*length(c1);
                            
                            break
                        else
                            acumulado(creacion(1,k))=sum(Total(3,creacion(1,1):creacion(1,k)));
                            %% seccion para las perdidas
                            acumulado_aux(creacion(1,k))=sum(Total(3,creacion(1,1):creacion(1,k)));
                            
                            Lt_aux(creacion(1,k))=Total(2,creacion(1,k))*acumulado_aux((creacion(1,k)));
                            
                            Parcial_aux(creacion(1,k))=Lt_aux(creacion(1,k))/Total(4,creacion(1,k));
                            
                            subtotal(creacion(1,k))=120*sum(Parcial_aux(creacion(1,1):creacion(1,k)))/100;
                            
                            corriente_conductor(creacion(1,k))=(acumulado_aux(creacion(1,k))*0.86*1000)/(v-subtotal(creacion(1,k)));
                            
                            resistencia_conductor(creacion(1,k))=resistividad*(Total(2,creacion(1,k))/radio_cuadrado);
                            
                            perdidaa_potencia(creacion(1,k))=resistencia_conductor(creacion(1,k))*( corriente_conductor(creacion(1,k))^2);
                            
                            total_2(k)=resistencia_conductor(creacion(1,k))*( corriente_conductor(creacion(1,k))^2);
                            
                        end
                    end
                    
                    
                    Lt(creacion(1,k))=Total(2,creacion(1,k))*acumulado((creacion(1,k)));
                    
                    Parcial(creacion(1,k))=Lt(creacion(1,k))/Total(4,creacion(1,k));
                    
                    total(k)=(Total(2,creacion(1,k))*acumulado((creacion(1,k))))/Total(4,creacion(1,k));
                    
                    %% almacenamiento


                end
                
                resultado.voltajes_extremos(j)=sum(total);
                
                resultado.perdidas(j)=sum(total_2)/1000;
                
                resultado.perdida.mes(j)=resultado.perdidas(j)*720;
                
                resultado.costo.mes(j)=(resultado.perdidas(j)*720)*0.11;
                
                resultado.costo.anual(j)=((resultado.perdidas(j)*720)*0.11)*12;
                
                resultado.perdida_total=sum(resultado.costo.anual(1:j));
                
            end
            dato_min=min(resultado.voltajes_extremos);
            dato_max=max(resultado.voltajes_extremos);
            
            resultado.variacion_extremos=abs(dato_min-dato_max);
            
            Datos=[Total;acumulado;Lt;Parcial;subtotal;resistencia_conductor;corriente_conductor;perdidaa_potencia];
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc,clear all,close all;
            %% datoa globales de poner los valores
            global POSTES LONG TIPO CALIBRE bandera_datos consumo_poste kva_m;
            
            %% datos globales de generar las entradas
            global Total_paquete dimensiones;
            
            global k;
            global bandera_calculo;
            
            global Total_final Resultado Verificacion;
            %% variables de la base de datosl;
            POSTES=[];
            LONG=[];
            TIPO=[];
            CALIBRE=[];
            bandera_datos=0;
            
            Total_paquete=[];
            dimensiones=[];
            
            consumo_poste=Consumo_poste(17);
            kva_m = kvam();
            
            k=0;
            
            bandera_calculo=0;
            Total_final=[];
            Resultado=[];
            Verificacion=[];

        end

        % Value changed function: Postes_1
        function Postes_1ValueChanged(app, event)
            global POSTES;
            aux_dimension=length(POSTES);
            if(aux_dimension>1)
                if (isempty(app.Postes_1.Value))
                    POSTES(1)=[];
                else
                    POSTES(1)= convertCharsToStrings(app.Postes_1.Value);
                end
            else
                if (isempty(app.Postes_1.Value))
                    POSTES=[];
                else
                    POSTES = convertCharsToStrings(app.Postes_1.Value);
                end
            end
            
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            global POSTES LONG TIPO CALIBRE Total bandera_datos kva_m consumo_poste;

            %% generacion de la variable auxiliar para verificar lac columnas
            auxi_dimension=[length(POSTES),length(LONG),length(TIPO),length(CALIBRE)];
            
            bandera_columna=all(auxi_dimension==length(POSTES));
            
            %% Seccion para verificar la correcta introduccion de los datos
            if(all(auxi_dimension==length(POSTES))) && (length(POSTES)>0)
                
                conductor=TIPO(:,:)+CALIBRE(:,:);
                %% verificacion si los datos se encuentran en la base de datos
                [consumo,aux_c] = datos_consumo(app,POSTES,consumo_poste);
                
                [conexion,aux_t] = tipo(app,"monofasica",kva_m,conductor);
                
                [Total,bandera_datos] = generacion_datos(app,str2double(POSTES),str2double(LONG),consumo,aux_c,conexion,aux_t);

            elseif (all(auxi_dimension==length(POSTES)))
                disp("Colocar Almenos una columna de datos");
                mensaje_inicio='Colocar Almenos una columna de datos';
                msgbox(mensaje_inicio,'Aviso Interface');
                bandera_datos=0;
            else
                disp("Acabar de colocar los datos de la columna");
                mensaje_inicio='Acabar de colocar los datos de la columna';
                msgbox(mensaje_inicio,'Aviso Interface');
                bandera_datos=0;
            end
            
            
        end

        % Value changed function: Postes_2
        function Postes_2ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_2.Value))
                POSTES(2)= [];
            else
                POSTES(2)= eval(app.Postes_2.Value);
            end
        end

        % Value changed function: Postes_3
        function Postes_3ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_3.Value))
                POSTES(3)= [];
            else
                POSTES(3)= eval(app.Postes_3.Value);
            end
        end

        % Value changed function: Longitud_1
        function Longitud_1ValueChanged(app, event)
            global LONG;
            aux_dimension=length(LONG);
            if(aux_dimension>1)
                if (isempty(app.Longitud_1.Value))
                    LONG(1)=[];
                else
                    LONG(1)= convertCharsToStrings(app.Longitud_1.Value);
                end
            else
                if (isempty(app.Longitud_1.Value))
                    LONG=[];
                else
                    LONG = convertCharsToStrings(app.Longitud_1.Value);
                end
            end
            
        end

        % Value changed function: Longitud_2
        function Longitud_2ValueChanged(app, event)
            global LONG;
            if (isempty(app.Longitud_2.Value))
               LONG(2)=[];
            else
                LONG(2)=eval(app.Longitud_2.Value);
            end
        end

        % Value changed function: Longitud_3
        function Longitud_3ValueChanged(app, event)
            global LONG;
            if (isempty(app.Longitud_3.Value))
               LONG(3)=[];
            else
                LONG(3)=eval(app.Longitud_3.Value);
            end
        end

        % Value changed function: Tipo_1
        function Tipo_1ValueChanged(app, event)
            global TIPO;
            aux_dimension=length(TIPO);
            if(aux_dimension>1)
                if (isempty(app.Tipo_1.Value))
                    TIPO(1)=[];
                else
                    TIPO(1)= convertCharsToStrings(app.Tipo_1.Value);
                end
            else
                if (isempty(app.Tipo_1.Value))
                    TIPO=[];
                else
                    TIPO = convertCharsToStrings(app.Tipo_1.Value);
                end
            end


        end

        % Value changed function: Tipo_2
        function Tipo_2ValueChanged(app, event)
            global TIPO;
            if (isempty(app.Tipo_2.Value))
                TIPO(2) = [];
            else
                TIPO(2) = convertCharsToStrings(app.Tipo_2.Value);
            end
            
            
        end

        % Value changed function: Tipo_3
        function Tipo_3ValueChanged(app, event)
            global TIPO;
            if (isempty(app.Tipo_3.Value))
                TIPO(3) = [];
            else
                TIPO(3) = convertCharsToStrings(app.Tipo_3.Value);
            end


        end

        % Value changed function: Calibre_1
        function Calibre_1ValueChanged(app, event)
            global CALIBRE;
            aux_dimension=length(CALIBRE);
            if(aux_dimension>1)
                if (isempty(app.Calibre_1.Value))
                    CALIBRE(1)=[];
                else
                    CALIBRE(1)= convertCharsToStrings(app.Calibre_1.Value);
                end
            else
                if (isempty(app.Calibre_1.Value))
                    CALIBRE=[];
                else
                    CALIBRE = convertCharsToStrings(app.Calibre_1.Value);
                end
            end
        end

        % Value changed function: Calibre_2
        function Calibre_2ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_2.Value))
                CALIBRE(2) = [];
            else
                CALIBRE(2) = convertCharsToStrings(app.Calibre_2.Value);
            end
        end

        % Value changed function: Calibre_3
        function Calibre_3ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_3.Value))
                CALIBRE(3) = [];
            else
                CALIBRE(3) = convertCharsToStrings(app.Calibre_3.Value);
            end
        end

        % Button pushed function: Cargar
        function CargarButtonPushed(app, event)
            global Total bandera_datos Total_paquete POSTES LONG TIPO CALIBRE dimensiones k posiciones_interaccion bandera_calculo;
            
            if bandera_datos==1
                
                k=k+1;
                if (k==1)
                    
                    %%bandera para permitir el calculo
                    bandera_calculo=1;
                    %% almacenar dimesiones
                    dimensiones=[dimensiones,size(Total(1,:))'];
                    %% declaracion de las posiciones para poder hacer el calculo
                    posiciones_interaccion=aux_posiciones(app,dimensiones);
                    %% crear la amtrix de datos
                    Total_paquete=[Total_paquete,Total];
                    %% guardar los datos en el directoriod e trabajo
                    save('Total_paquete.mat','Total_paquete');
                    
                    %% REINICIO DE LAS VARBALES
                    Total=[];
                    bandera_datos=0;
                    POSTES=[];
                    LONG=[];
                    TIPO=[];
                    CALIBRE=[];
                    
                    %% BORRAR DATOS EN PANTALLA D LAS VARIABLES
                        app.Postes_1.Value="";
                        app.Longitud_1.Value="";
                        app.Tipo_1.Value="";
                        app.Calibre_1.Value="";
                        
                        app.Postes_2.Value="";
                        app.Longitud_2.Value="";
                        app.Tipo_2.Value="";
                        app.Calibre_2.Value="";
                        
                        app.Postes_3.Value="";
                        app.Longitud_3.Value="";
                        app.Tipo_3.Value="";
                        app.Calibre_3.Value="";
                        
                        app.Postes_4.Value="";
                        app.Longitud_4.Value="";
                        app.Tipo_4.Value="";
                        app.Calibre_4.Value="";
                        
                        app.Postes_5.Value="";
                        app.Longitud_5.Value="";
                        app.Tipo_5.Value="";
                        app.Calibre_5.Value="";
                        
                        app.Postes_6.Value="";
                        app.Longitud_6.Value="";
                        app.Tipo_6.Value="";
                        app.Calibre_6.Value="";
                    
                    disp("Datos cargados seguir ingresando datos o dirigirse a calculos");
                    mensaje_inicio='Datos cargados seguir ingresando datos o dirigirse a calculos';
                    msgbox(mensaje_inicio,'Aviso Interface');
                else
                    
                    
                    nuevo=aux_posiciones(app,dimensiones);
                    bandera_calculo=1;
                    if Total_paquete(1,nuevo(1,end))==Total(1,1)
                        disp("No se permite los mismos postes en la posicion 1");
                        mensaje_inicio='No se permite los mismos postes en la posicion 1"';
                        msgbox(mensaje_inicio,'Aviso Interface');
                    else
                        dimensiones=[dimensiones,size(Total(1,:))'];
                        
                        posiciones_interaccion=aux_posiciones(app,dimensiones);
                        
                        Total_paquete=[Total_paquete,Total];
                        %% guardar los datos en el directoriod e trabajo
                        save('Total_paquete.mat','Total_paquete');
                        
                        %% REINICIO DE LAS VARBALES
                        Total=[];
                        bandera_datos=0;
                        POSTES=[];
                        LONG=[];
                        TIPO=[];
                        CALIBRE=[];
                        
                        %% BORRAR DATOS EN PANTALLA D LAS VARIABLES
                        app.Postes_1.Value="";
                        app.Longitud_1.Value="";
                        app.Tipo_1.Value="";
                        app.Calibre_1.Value="";
                        
                        app.Postes_2.Value="";
                        app.Longitud_2.Value="";
                        app.Tipo_2.Value="";
                        app.Calibre_2.Value="";
                        
                        app.Postes_3.Value="";
                        app.Longitud_3.Value="";
                        app.Tipo_3.Value="";
                        app.Calibre_3.Value="";
                        
                        app.Postes_4.Value="";
                        app.Longitud_4.Value="";
                        app.Tipo_4.Value="";
                        app.Calibre_4.Value="";
                        
                        app.Postes_5.Value="";
                        app.Longitud_5.Value="";
                        app.Tipo_5.Value="";
                        app.Calibre_5.Value="";
                        
                        app.Postes_6.Value="";
                        app.Longitud_6.Value="";
                        app.Tipo_6.Value="";
                        app.Calibre_6.Value="";
                        
                        disp("Datos cargados seguir ingresando datos o dirigirse a calculos");
                        mensaje_inicio='Datos cargados seguir ingresando datos o dirigirse a calculos';
                        msgbox(mensaje_inicio,'Aviso Interface');
                    
                    end
                    

                end
                
            else
                disp("Accion no permitida, aun no se ingresan y verifican sus datos");
                mensaje_inicio="Accion no permitida, aun no se ingresan y verifican sus datos";
                msgbox(mensaje_inicio,'Aviso Interface');
            end
        end

        % Value changed function: Postes_4
        function Postes_4ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_4.Value))
                POSTES(4)= [];
            else
                POSTES(4)= eval(app.Postes_4.Value);
            end
            
        end

        % Value changed function: Postes_5
        function Postes_5ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_5.Value))
                POSTES(5)= [];
            else
                POSTES(5)= eval(app.Postes_5.Value);
            end
        end

        % Value changed function: Postes_6
        function Postes_6ValueChanged(app, event)
            global POSTES;
            if (isempty(app.Postes_6.Value))
                POSTES(6)= [];
            else
                POSTES(6)= eval(app.Postes_6.Value);
            end
        end

        % Value changed function: Longitud_4
        function Longitud_4ValueChanged(app, event)
            global LONG;
            if (isempty(app.Longitud_4.Value))
               LONG(4)=[];
            else
                LONG(4)=eval(app.Longitud_4.Value);
            end
        end

        % Value changed function: Longitud_5
        function Longitud_5ValueChanged(app, event)
            global LONG;
            if (isempty(app.Longitud_5.Value))
               LONG(5)=[];
            else
                LONG(5)=eval(app.Longitud_5.Value);
            end
        end

        % Value changed function: Longitud_6
        function Longitud_6ValueChanged(app, event)

            global LONG;
            if (isempty(app.Longitud_6.Value))
               LONG(6)=[];
            else
                LONG(6)=eval(app.Longitud_6.Value);
            end
        end

        % Value changed function: Tipo_4
        function Tipo_4ValueChanged(app, event)
            global TIPO;
            if (isempty(app.Tipo_4.Value))
                TIPO(4) = [];
            else
                TIPO(4) = convertCharsToStrings(app.Tipo_4.Value);
            end
        end

        % Value changed function: Tipo_5
        function Tipo_5ValueChanged(app, event)
             global TIPO;
            if (isempty(app.Tipo_5.Value))
                TIPO(5) = [];
            else
                TIPO(5) = convertCharsToStrings(app.Tipo_5.Value);
            end
        end

        % Value changed function: Tipo_6
        function Tipo_6ValueChanged(app, event)
            global TIPO;
            if (isempty(app.Tipo_6.Value))
                TIPO(6) = [];
            else
                TIPO(6) = convertCharsToStrings(app.Tipo_6.Value);
            end
        end

        % Value changed function: Calibre_4
        function Calibre_4ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_4.Value))
                CALIBRE(4) = [];
            else
                CALIBRE(4) = convertCharsToStrings(app.Calibre_4.Value);
            end
        end

        % Value changed function: Calibre_5
        function Calibre_5ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_5.Value))
                CALIBRE(5) = [];
            else
                CALIBRE(5) = convertCharsToStrings(app.Calibre_5.Value);
            end
        end

        % Value changed function: Calibre_6
        function Calibre_6ValueChanged(app, event)
            global CALIBRE;
            if (isempty(app.Calibre_6.Value))
                CALIBRE(6) = [];
            else
                CALIBRE(6) = convertCharsToStrings(app.Calibre_6.Value);
            end
        end

        % Button pushed function: CalculoVoltajesButton
        function CalculoVoltajesButtonPushed(app, event)
            global bandera_calculo posiciones_interaccion Total_paquete Total_final Resultado Verificacion;
            
            if bandera_calculo==1
                cla(app.Errores_posicion_proporcional,"reset");
                
                title(app.Errores_posicion_proporcional,'$\textrm{Voltajes Extremos}$','Interpreter','latex','FontSize',12);
                app.Errores_posicion_proporcional.XMinorGrid='on'; 
                app.Errores_posicion_proporcional.YMinorGrid='on'; 
                xlabel(app.Errores_posicion_proporcional,'$\textrm{Punto}[s]$','Interpreter','latex','FontSize',9); 
                ylabel(app.Errores_posicion_proporcional,'$\textrm{Variacion}[s]$','Interpreter','latex','FontSize',9); 
%                 barra=bar(app.Errores_posicion_proporcional,nan,nan);
                
                Parametros.voltage=app.VoltajeEditField.Value
                Parametros.resistividad=app.ResistividadEditField.Value
                Parametros.diametro=app.DiametroEditField.Value
                Parametros.radio=Parametros.diametro/2;
                Parametros.operador=Parametros.radio^2;
                
                [Total_final,busqueda,C]= voltajes_extremos(app,posiciones_interaccion,Total_paquete);
                save('Total_final.mat','Total_final');
                disp("Calculo realizado correctamente");
                mensaje_inicio="Calculo realizado correctamente";
                msgbox(mensaje_inicio,'Aviso Interface');
                [Resultado,Verificacion] = extremos_final(app,Total_final,posiciones_interaccion,busqueda,Parametros);
                save('Resultado.mat','Resultado');
                save('Verificacion.mat','Verificacion');
                disp("Calculo voltajes extremos");
                disp(Resultado.voltajes_extremos);
                disp("Calculo variacion_extremos");
                disp(Resultado.variacion_extremos);
                disp("Calculo perdida total");
                disp(Resultado.perdida_total);
                
                eje_x=Verificacion(1,posiciones_interaccion(1,:));
                
                
                bar(app.Errores_posicion_proporcional,eje_x,Resultado.voltajes_extremos);
%                 legend(app.Errores_posicion_proporcional,{'$\tilde{x_{p}}$','$\tilde{y_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
%                 legend(app.Errores_posicion_proporcional,'boxoff');
%                 app.Calculos_voltajes.Value=Resultado;
            else
                disp("No es posible el calculo aun no se cargan valores");
                mensaje_inicio="No es posible el calculo aun no se cargan valores";
                msgbox(mensaje_inicio,'Aviso Interface');
            end
        end

        % Value changed function: VoltajeEditField
        function VoltajeEditFieldValueChanged(app, event)
            value = app.VoltajeEditField.Value;
            
        end

        % Value changed function: ResistividadEditField
        function ResistividadEditFieldValueChanged(app, event)
            value = app.ResistividadEditField.Value;
            
        end

        % Value changed function: DiametroEditField
        function DiametroEditFieldValueChanged(app, event)
            value = app.DiametroEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.General = uitabgroup(app.UIFigure);
            app.Inicio = uitab(app.General);
            app.Inicio.Title = 'Datos';
            app.Tipo_1 = uieditfield(app.Inicio, 'text');
            app.Tipo_1.ValueChangedFcn = createCallbackFcn(app, @Tipo_1ValueChanged, true);
            app.UniversidadUTELabel = uilabel(app.Inicio);
            app.UniversidadUTELabel.Text = 'Universidad UTE';
            app.Tipo_2 = uieditfield(app.Inicio, 'text');
            app.Tipo_2.ValueChangedFcn = createCallbackFcn(app, @Tipo_2ValueChanged, true);
            app.Tipo_3 = uieditfield(app.Inicio, 'text');
            app.Tipo_3.ValueChangedFcn = createCallbackFcn(app, @Tipo_3ValueChanged, true);
            app.Tipo_4 = uieditfield(app.Inicio, 'text');
            app.Tipo_4.ValueChangedFcn = createCallbackFcn(app, @Tipo_4ValueChanged, true);
            app.Tipo_5 = uieditfield(app.Inicio, 'text');
            app.Tipo_5.ValueChangedFcn = createCallbackFcn(app, @Tipo_5ValueChanged, true);
            app.Tipo_6 = uieditfield(app.Inicio, 'text');
            app.Tipo_6.ValueChangedFcn = createCallbackFcn(app, @Tipo_6ValueChanged, true);
            app.PostesLabel = uilabel(app.Inicio);
            app.PostesLabel.Text = 'Postes';
            app.LongitudLabel = uilabel(app.Inicio);
            app.LongitudLabel.Text = 'Longitud';
            app.TipoLabel = uilabel(app.Inicio);
            app.TipoLabel.Text = 'Tipo';
            app.Calibre_1 = uieditfield(app.Inicio, 'text');
            app.Calibre_1.ValueChangedFcn = createCallbackFcn(app, @Calibre_1ValueChanged, true);
            app.CalibreLabel = uilabel(app.Inicio);
            app.CalibreLabel.Text = 'Calibre';
            app.Calibre_2 = uieditfield(app.Inicio, 'text');
            app.Calibre_2.ValueChangedFcn = createCallbackFcn(app, @Calibre_2ValueChanged, true);
            app.Calibre_3 = uieditfield(app.Inicio, 'text');
            app.Calibre_3.ValueChangedFcn = createCallbackFcn(app, @Calibre_3ValueChanged, true);
            app.Calibre_4 = uieditfield(app.Inicio, 'text');
            app.Calibre_4.ValueChangedFcn = createCallbackFcn(app, @Calibre_4ValueChanged, true);
            app.Calibre_5 = uieditfield(app.Inicio, 'text');
            app.Calibre_5.ValueChangedFcn = createCallbackFcn(app, @Calibre_5ValueChanged, true);
            app.Calibre_6 = uieditfield(app.Inicio, 'text');
            app.Calibre_6.ValueChangedFcn = createCallbackFcn(app, @Calibre_6ValueChanged, true);
            app.Longitud_1 = uieditfield(app.Inicio, 'text');
            app.Longitud_1.ValueChangedFcn = createCallbackFcn(app, @Longitud_1ValueChanged, true);
            app.Longitud_2 = uieditfield(app.Inicio, 'text');
            app.Longitud_2.ValueChangedFcn = createCallbackFcn(app, @Longitud_2ValueChanged, true);
            app.Longitud_3 = uieditfield(app.Inicio, 'text');
            app.Longitud_3.ValueChangedFcn = createCallbackFcn(app, @Longitud_3ValueChanged, true);
            app.Longitud_4 = uieditfield(app.Inicio, 'text');
            app.Longitud_4.ValueChangedFcn = createCallbackFcn(app, @Longitud_4ValueChanged, true);
            app.Longitud_5 = uieditfield(app.Inicio, 'text');
            app.Longitud_5.ValueChangedFcn = createCallbackFcn(app, @Longitud_5ValueChanged, true);
            app.Longitud_6 = uieditfield(app.Inicio, 'text');
            app.Longitud_6.ValueChangedFcn = createCallbackFcn(app, @Longitud_6ValueChanged, true);
            app.Postes_1 = uieditfield(app.Inicio, 'text');
            app.Postes_1.ValueChangedFcn = createCallbackFcn(app, @Postes_1ValueChanged, true);
            app.Postes_2 = uieditfield(app.Inicio, 'text');
            app.Postes_2.ValueChangedFcn = createCallbackFcn(app, @Postes_2ValueChanged, true);
            app.Postes_3 = uieditfield(app.Inicio, 'text');
            app.Postes_3.ValueChangedFcn = createCallbackFcn(app, @Postes_3ValueChanged, true);
            app.Postes_4 = uieditfield(app.Inicio, 'text');
            app.Postes_4.ValueChangedFcn = createCallbackFcn(app, @Postes_4ValueChanged, true);
            app.Postes_5 = uieditfield(app.Inicio, 'text');
            app.Postes_5.ValueChangedFcn = createCallbackFcn(app, @Postes_5ValueChanged, true);
            app.Postes_6 = uieditfield(app.Inicio, 'text');
            app.Postes_6.ValueChangedFcn = createCallbackFcn(app, @Postes_6ValueChanged, true);
            app.ActualizarButton = uibutton(app.Inicio, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.Cargar = uibutton(app.Inicio, 'push');
            app.Cargar.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.Cargar.Text = 'Cargar';
            app.DatosLabel = uilabel(app.Inicio);
            app.DatosLabel.Text = 'Datos';
            app.CalculosTab = uitab(app.General);
            app.CalculosTab.Title = 'Calculos';
            app.UniversidadUTELabel_2 = uilabel(app.CalculosTab);
            app.UniversidadUTELabel_2.Text = 'Universidad UTE';
            app.CaidavoltajesLabel = uilabel(app.CalculosTab);
            app.CaidavoltajesLabel.Text = 'Caida voltajes';
            app.CalculoVoltajesButton = uibutton(app.CalculosTab, 'push');
            app.CalculoVoltajesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculoVoltajesButtonPushed, true);
            app.CalculoVoltajesButton.Text = 'Calculo Voltajes';
            app.Errores_posicion_proporcional = uiaxes(app.CalculosTab);
            title(app.Errores_posicion_proporcional, 'Voltajes Extremos')
            xlabel(app.Errores_posicion_proporcional, 'Punto')
            ylabel(app.Errores_posicion_proporcional, 'Variacion')
            app.VoltajeEditFieldLabel = uilabel(app.CalculosTab);
            app.VoltajeEditFieldLabel.Text = 'Voltaje';
            app.VoltajeEditField = uieditfield(app.CalculosTab, 'numeric');
            app.VoltajeEditField.ValueChangedFcn = createCallbackFcn(app, @VoltajeEditFieldValueChanged, true);
            app.VoltajeEditField.Value = 120;
            app.ResistividadEditFieldLabel = uilabel(app.CalculosTab);
            app.ResistividadEditFieldLabel.Text = 'Resistividad';
            app.ResistividadEditField = uieditfield(app.CalculosTab, 'numeric');
            app.ResistividadEditField.ValueChangedFcn = createCallbackFcn(app, @ResistividadEditFieldValueChanged, true);
            app.ResistividadEditField.Value = 0.028;
            app.DiametroEditFieldLabel = uilabel(app.CalculosTab);
            app.DiametroEditFieldLabel.Text = 'Diametro';
            app.DiametroEditField = uieditfield(app.CalculosTab, 'numeric');
            app.DiametroEditField.ValueChangedFcn = createCallbackFcn(app, @DiametroEditFieldValueChanged, true);
            app.DiametroEditField.Value = 7.41;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface_redes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end